classdef FinalDSTT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LiqutrnhPanel       matlab.ui.container.Panel
        TextArea            matlab.ui.control.TextArea
        thPanel             matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        DliuPanel           matlab.ui.container.Panel
        MatrnOutputLabel    matlab.ui.control.Label
        MatrnInputLabel     matlab.ui.control.Label
        OutputTable         matlab.ui.control.Table
        InputTable          matlab.ui.control.Table
        Panel               matlab.ui.container.Panel
        TnhtonButton        matlab.ui.control.Button
        ResetButton         matlab.ui.control.Button
        TobngButton         matlab.ui.control.Button
        SctEditField        matlab.ui.control.NumericEditField
        SctEditFieldLabel   matlab.ui.control.Label
        ShngEditField       matlab.ui.control.NumericEditField
        ShngEditFieldLabel  matlab.ui.control.Label
        thvhngButton        matlab.ui.control.Button
        thchngButton        matlab.ui.control.Button
        TI08THUTTONFLOYDWARSHALLTMNGINGNNHTLabel  matlab.ui.control.Label
        CN01Nhm8Label       matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: thvhngButton
        function thvhngButtonPushed(app, event)
            app.thchngButton.Enable="off";
            app.ResetButton.Enable="on";

            app.ShngEditField.Enable="on";
            app.SctEditField.Enable="on";
            app.TobngButton.Enable="on";

            app.thvhngButton.BackgroundColor=[0.07,0.62,1.00];
        end

        % Button pushed function: thchngButton
        function thchngButtonPushed(app, event)
            app.thvhngButton.Enable="off";
            app.ResetButton.Enable="on";

            app.ShngEditField.Enable="on";
            app.SctEditField.Enable="on";
            app.TobngButton.Enable="on";

            app.thchngButton.BackgroundColor=[0.07,0.62,1.00];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.thvhngButton.Enable="on";
            app.thchngButton.Enable="on";
            app.TnhtonButton.Enable="off";
            app.ResetButton.Enable="off";

            app.ShngEditField.Enable="off";
            app.SctEditField.Enable="off";
            app.TobngButton.Enable="off";

            app.thchngButton.BackgroundColor=[0.96,0.96,0.96];
            app.thvhngButton.BackgroundColor=[0.96,0.96,0.96];
        end

        % Button pushed function: TobngButton
        function TobngButtonPushed(app, event)
            app.TnhtonButton.Enable="on";
            
            numRows = round(app.ShngEditField.Value);
            numCols = round(app.SctEditField.Value);

            data = cell(numRows, numCols);

            app.InputTable.Data = data;
            app.OutputTable.Data = data;

            colNames = arrayfun(@(x) sprintf('%d', x), 1:numCols, 'UniformOutput', false);
            app.InputTable.ColumnName = colNames;
            app.OutputTable.ColumnName = colNames;
            
            rowNames = arrayfun(@(x) sprintf('%d', x), 1:numRows, 'UniformOutput', false);
            app.OutputTable.RowName = rowNames;
            app.InputTable.RowName = rowNames;

            app.OutputTable.Enable = "off";
        end

        % Button pushed function: TnhtonButton
        function TnhtonButtonPushed(app, event)
            data = app.InputTable.Data; % Get data from input table
            
            % Save data to input.txt
            fileID = fopen('input.txt', 'w');
            [rows, cols] = size(data);
            for i = 1:rows
                for j = 1:cols
                    fprintf(fileID, '%s ', string(data{i, j}));
                end
                fprintf(fileID, '\n');
            end
            fclose(fileID);
            
            %system('run.exe')
            %OutputData = readmatrix('Output.txt');
            %app.OutputTable.Data = OutputData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CN01Nhm8Label = uilabel(app.UIFigure);
            app.CN01Nhm8Label.Text = 'CN01 - Nhóm 8';
            app.TI08THUTTONFLOYDWARSHALLTMNGINGNNHTLabel = uilabel(app.UIFigure);
            app.TI08THUTTONFLOYDWARSHALLTMNGINGNNHTLabel.Text = 'ĐỀ TÀI 08: THUẬT TOÁN FLOYD - WARSHALL TÌM ĐƯỜNG ĐI NGẮN NHẤT';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.thchngButton = uibutton(app.Panel, 'push');
            app.thchngButton.ButtonPushedFcn = createCallbackFcn(app, @thchngButtonPushed, true);
            app.thchngButton.Text = 'Đồ thị có hướng';
            app.thvhngButton = uibutton(app.Panel, 'push');
            app.thvhngButton.ButtonPushedFcn = createCallbackFcn(app, @thvhngButtonPushed, true);
            app.thvhngButton.Text = 'Đồ thị vô hướng';
            app.ShngEditFieldLabel = uilabel(app.Panel);
            app.ShngEditFieldLabel.Text = 'Số hàng';
            app.ShngEditField = uieditfield(app.Panel, 'numeric');
            app.ShngEditField.RoundFractionalValues = 'on';
            app.ShngEditField.Enable = 'off';
            app.SctEditFieldLabel = uilabel(app.Panel);
            app.SctEditFieldLabel.Text = 'Số cột';
            app.SctEditField = uieditfield(app.Panel, 'numeric');
            app.SctEditField.RoundFractionalValues = 'on';
            app.SctEditField.Enable = 'off';
            app.TobngButton = uibutton(app.Panel, 'push');
            app.TobngButton.ButtonPushedFcn = createCallbackFcn(app, @TobngButtonPushed, true);
            app.TobngButton.Enable = 'off';
            app.TobngButton.Text = 'Tạo bảng';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.TnhtonButton = uibutton(app.Panel, 'push');
            app.TnhtonButton.ButtonPushedFcn = createCallbackFcn(app, @TnhtonButtonPushed, true);
            app.TnhtonButton.Enable = 'off';
            app.TnhtonButton.Text = 'Tính toán';
            app.DliuPanel = uipanel(app.UIFigure);
            app.DliuPanel.Title = 'Dữ liệu';
            app.InputTable = uitable(app.DliuPanel);
            app.InputTable.ColumnName = {'1'; '2'; '3'; '4'};
            app.InputTable.RowName = {'1,2,3,4'};
            app.InputTable.ColumnEditable = true;
            app.OutputTable = uitable(app.DliuPanel);
            app.OutputTable.ColumnName = {'1'; '2'; '3'; '4'};
            app.OutputTable.RowName = {'1,2,3,4'};
            app.OutputTable.ColumnEditable = true;
            app.OutputTable.Enable = 'off';
            app.MatrnInputLabel = uilabel(app.DliuPanel);
            app.MatrnInputLabel.Text = 'Ma trận Input';
            app.MatrnOutputLabel = uilabel(app.DliuPanel);
            app.MatrnOutputLabel.Text = 'Ma trận Output';
            app.thPanel = uipanel(app.UIFigure);
            app.thPanel.Title = 'Đồ thị';
            app.UIAxes = uiaxes(app.thPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LiqutrnhPanel = uipanel(app.UIFigure);
            app.LiqutrnhPanel.Title = 'Lỗi quá trình';
            app.TextArea = uitextarea(app.LiqutrnhPanel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalDSTT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end